classdef aerial_arm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        GridLayout7          matlab.ui.container.GridLayout
        Panel                matlab.ui.container.Panel
        default_all          matlab.ui.control.Button
        arm_path             matlab.ui.control.EditField
        EditField_2Label     matlab.ui.control.Label
        pdes                 matlab.ui.control.EditField
        Label                matlab.ui.control.Label
        set_des_0            matlab.ui.control.Button
        TabGroup3            matlab.ui.container.TabGroup
        Tab                  matlab.ui.container.Tab
        L                    matlab.ui.control.EditField
        mEditFieldLabel_2    matlab.ui.control.Label
        Izz                  matlab.ui.control.EditField
        IzzEditFieldLabel_2  matlab.ui.control.Label
        Iyy                  matlab.ui.control.EditField
        IyyEditFieldLabel_2  matlab.ui.control.Label
        Ixx                  matlab.ui.control.EditField
        IxxEditFieldLabel_2  matlab.ui.control.Label
        mb                   matlab.ui.control.EditField
        kgEditFieldLabel_2   matlab.ui.control.Label
        Tab_2                matlab.ui.container.Tab
        hL2                  matlab.ui.control.EditField
        EditField_4Label     matlab.ui.control.Label
        hL1                  matlab.ui.control.EditField
        EditField_3Label     matlab.ui.control.Label
        mL2                  matlab.ui.control.EditField
        kgEditField_3Label   matlab.ui.control.Label
        mL1                  matlab.ui.control.EditField
        kgEditField_2Label   matlab.ui.control.Label
        L2                   matlab.ui.control.EditField
        mEditField_3Label    matlab.ui.control.Label
        L1                   matlab.ui.control.EditField
        mEditField_2Label    matlab.ui.control.Label
        CenterPanel          matlab.ui.container.Panel
        GridLayout3          matlab.ui.container.GridLayout
        GridLayout4          matlab.ui.container.GridLayout
        TabGroup2            matlab.ui.container.TabGroup
        track                matlab.ui.container.Tab
        GridLayout6          matlab.ui.container.GridLayout
        track_attitude       matlab.ui.control.UIAxes
        track_position       matlab.ui.control.UIAxes
        grasp1step           matlab.ui.container.Tab
        GridLayout8          matlab.ui.container.GridLayout
        step_mpc             matlab.ui.control.UIAxes
        step_pid             matlab.ui.control.UIAxes
        trajectory           matlab.ui.container.Tab
        GridLayout9          matlab.ui.container.GridLayout
        arm_traj_s           matlab.ui.control.UIAxes
        fly_traj_s           matlab.ui.control.UIAxes
        grasp_traj           matlab.ui.container.Tab
        traj_grasp           matlab.ui.container.GridLayout
        mpc_xz               matlab.ui.control.UIAxes
        pid_xz               matlab.ui.control.UIAxes
        traj_mpc             matlab.ui.control.UIAxes
        traj_pid             matlab.ui.control.UIAxes
        Tab_3                matlab.ui.container.Tab
        total                matlab.ui.container.GridLayout
        attitude_total       matlab.ui.control.UIAxes
        position_total       matlab.ui.control.UIAxes
        RightPanel           matlab.ui.container.Panel
        GridLayout5          matlab.ui.container.GridLayout
        Panel_2              matlab.ui.container.Panel
        start_1step_grasp    matlab.ui.control.Button
        start_track          matlab.ui.control.Button
        start_simulation     matlab.ui.control.Button
        control_para         matlab.ui.container.TabGroup
        track_control        matlab.ui.container.Tab
        kctp                 matlab.ui.control.EditField
        kctpLabel            matlab.ui.control.Label
        kati                 matlab.ui.control.EditField
        katiLabel            matlab.ui.control.Label
        katp                 matlab.ui.control.EditField
        katpLabel            matlab.ui.control.Label
        kctd                 matlab.ui.control.EditField
        kctdLabel            matlab.ui.control.Label
        kcti                 matlab.ui.control.EditField
        kctiLabel            matlab.ui.control.Label
        attitude_control     matlab.ui.container.Tab
        kdph                 matlab.ui.control.EditField
        kdphLabel            matlab.ui.control.Label
        kpph                 matlab.ui.control.EditField
        kpphLabel            matlab.ui.control.Label
        kdt                  matlab.ui.control.EditField
        kdtLabel             matlab.ui.control.Label
        kpt                  matlab.ui.control.EditField
        kptLabel             matlab.ui.control.Label
        kdp                  matlab.ui.control.EditField
        kdpEditFieldLabel    matlab.ui.control.Label
        kpp                  matlab.ui.control.EditField
        kppEditFieldLabel    matlab.ui.control.Label
        hover                matlab.ui.container.Tab
        kiz                  matlab.ui.control.EditField
        kizEditFieldLabel    matlab.ui.control.Label
        kpz                  matlab.ui.control.EditField
        kpzEditFieldLabel    matlab.ui.control.Label
        kdz                  matlab.ui.control.EditField
        kdzEditFieldLabel    matlab.ui.control.Label
        kiy                  matlab.ui.control.EditField
        kiyEditFieldLabel    matlab.ui.control.Label
        kpy                  matlab.ui.control.EditField
        kpyEditFieldLabel    matlab.ui.control.Label
        kdy                  matlab.ui.control.EditField
        kdyEditFieldLabel    matlab.ui.control.Label
        kix                  matlab.ui.control.EditField
        kixEditFieldLabel    matlab.ui.control.Label
        kpx                  matlab.ui.control.EditField
        kpxEditFieldLabel    matlab.ui.control.Label
        kdx                  matlab.ui.control.EditField
        kdxEditFieldLabel    matlab.ui.control.Label
        grasp_pid            matlab.ui.container.Tab
        ktd                  matlab.ui.control.EditField
        ktdEditFieldLabel    matlab.ui.control.Label
        ktp                  matlab.ui.control.EditField
        ktpEditFieldLabel    matlab.ui.control.Label
        kdq2                 matlab.ui.control.EditField
        kdq2EditFieldLabel   matlab.ui.control.Label
        kiq2                 matlab.ui.control.EditField
        kiq2EditFieldLabel   matlab.ui.control.Label
        kpq2                 matlab.ui.control.EditField
        kpq2EditFieldLabel   matlab.ui.control.Label
        kdq1                 matlab.ui.control.EditField
        kdq1EditFieldLabel   matlab.ui.control.Label
        kiq1                 matlab.ui.control.EditField
        kiq1EditFieldLabel   matlab.ui.control.Label
        kpq1                 matlab.ui.control.EditField
        kpq1EditFieldLabel   matlab.ui.control.Label
        kdz_g                matlab.ui.control.EditField
        kdzEditFieldLabel_2  matlab.ui.control.Label
        kiz_g                matlab.ui.control.EditField
        kizEditFieldLabel_2  matlab.ui.control.Label
        kpz_g                matlab.ui.control.EditField
        kpzEditFieldLabel_2  matlab.ui.control.Label
        kdx_g                matlab.ui.control.EditField
        kdxEditField_3Label  matlab.ui.control.Label
        kix_g                matlab.ui.control.EditField
        kixEditFieldLabel_2  matlab.ui.control.Label
        kpx_g                matlab.ui.control.EditField
        kpxEditFieldLabel_2  matlab.ui.control.Label
        grasp_mpc            matlab.ui.container.Tab
        opt_num              matlab.ui.control.EditField
        EditField_5Label     matlab.ui.control.Label
        Np                   matlab.ui.control.EditField
        NpEditFieldLabel     matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {580, 580, 580};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {580, 580};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {242, '1x', 242};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: default_all
        function default_allButtonPushed(app, event)
            app.mb.Value = '1.8';
            app.Ixx.Value = '0.342';
            app.Iyy.Value = '0.342';
            app.Izz.Value = '0.474';
            app.L.Value = '0.3';
            
            app.L1.Value = '0.12';
            app.L2.Value = '0.12';
            app.mL1.Value = '0.08';
            app.mL2.Value = '0.08';
            app.hL1.Value = '0.001';
            app.hL2.Value = '0.001';
            
            app.pdes.Value = '[0 0 0;0 0 4;4 0 4;4 4 4]';
            app.arm_path.Value = '[pi/2 0; pi/4 pi/2; pi/3  2*pi/3]';
            
            app.kctp.Value='5';
            app.kcti.Value='3e-4';
            app.kctd.Value='1';
            app.katp.Value='5';
            app.kati.Value='3e-4';  
            
            app.kpph.Value='2000';
            app.kdph.Value='4000';
            app.kpt.Value='2000';
            app.kdt.Value='4000';
            app.kpp.Value='800';
            app.kdp.Value='4000';
            
            app.kpx.Value='1';
            app.kix.Value='0';
            app.kdx.Value='0.8';
            app.kpy.Value='1';
            app.kiy.Value='0';
            app.kdy.Value='0.8';
            app.kpz.Value='1.8';
            app.kiz.Value='0';
            app.kdz.Value='1.5';
            
            app.kpx_g.Value='180';
            app.kix_g.Value='10';
            app.kdx_g.Value='17';
            app.kpz_g.Value='50';
            app.kiz_g.Value='0';
            app.kdz_g.Value='50';
            
            app.kpq1.Value='1200';
            app.kiq1.Value='500';
            app.kdq1.Value='800';
            
            app.kpq2.Value='2000';
            app.kiq2.Value='1000';
            app.kdq2.Value='300';
            
            app.ktd.Value='10';
            app.ktp.Value='1';
            
            app.Np.Value='8';
            app.opt_num.Value='5';
        end

        % Button pushed function: start_simulation
        function start_simulationButtonPushed(app, event)

            %% 飞行追踪
            pdes=str2num(app.pdes.Value);
            pointnum=length(pdes)-2;
            final = pdes(length(pdes),:);
            xdes = final(1); ydes = final(2); zdes = final(3);
            vdes = 2;
            psides = 0;
            
            mb=str2num(app.mb.Value);
            mL1=str2num(app.mL1.Value);
            mL2=str2num(app.mL2.Value);
            L=str2num(app.L.Value);
                        
            mq = mb;
            mg = mL1+mL2;
            mobj = 0; 
            ms = mq+mg+mobj;
            
            Ixx=str2num(app.Ixx.Value);
            Iyy=str2num(app.Iyy.Value);
            Izz=str2num(app.Izz.Value);
            
            Kf = 6.11e-8*3600/4/pi^2;
            Km = 1.5e-9*3600/4/pi^2; 
            g = 9.8; 
            km=20;
            
            kctp=str2num(app.kctp.Value);
            kcti=str2num(app.kcti.Value);
            kctd=str2num(app.kctd.Value);
            katp=str2num(app.katp.Value);
            kati=str2num(app.kati.Value);
            kpph=str2num(app.kpph.Value);
            kdph=str2num(app.kdph.Value);
            kpt=str2num(app.kpt.Value);
            kdt=str2num(app.kdt.Value);
            kpp=str2num(app.kpp.Value);
            kdp=str2num(app.kdp.Value);
            kpx=str2num(app.kpx.Value);
            kix=str2num(app.kix.Value);
            kdx=str2num(app.kdx.Value);
            kpy=str2num(app.kpy.Value);
            kiy=str2num(app.kiy.Value);
            kdy=str2num(app.kdy.Value);
            kpz=str2num(app.kpz.Value);
            kiz=str2num(app.kiz.Value);
            kdz=str2num(app.kdz.Value);
            
            wh=sqrt(ms*g/4/Kf);
            
            options = simset('SrcWorkspace','current'); %设当前工作空间为参数变量所在的空间
            
            sim('track',50,options);
            
            q1_track=90*ones(size(time_track,1),1);
            q2_track=zeros(size(time_track,1),1);
           
            cla(app.track_position);
            plot(app.track_position,time_track,x_track,'-b','linewidth',1.2);
            hold(app.track_position,"on");
            plot(app.track_position,time_track,y_track,'--r','linewidth',1.2);
            hold(app.track_position,"on");
            plot(app.track_position,time_track,z_track,'-.k','linewidth',1.2);
            hold(app.track_position,"on");
            grid(app.track_position,"on");
            hold(app.track_position,"off");
            legend(app.track_position,"x","y","z");
            
            cla(app.track_attitude);
            plot(app.track_attitude,time_track,psi_track,'-b','linewidth',1.2);
            hold(app.track_attitude,"on");
            plot(app.track_attitude,time_track,theta_track,'--r','linewidth',1.2);
            hold(app.track_attitude,"on");
            plot(app.track_attitude,time_track,fai_track,'-.k','linewidth',1.2);
            hold(app.track_attitude,"on");
            grid(app.track_attitude,"on");
            hold(app.track_position,"off");
            legend(app.track_attitude,"psi","theta","phi");
            
            
            %% 单步抓取
            e3 = [0;0;1]; 
            g = 9.8;
            L1=str2num(app.L1.Value);
            L2=str2num(app.L2.Value);
            hL1=str2num(app.hL1.Value);
            hL2=str2num(app.hL2.Value);
            Hb = [Ixx 0 0;0 Iyy 0;0 0 Izz]; % m^2.kg
            HL1 = [0 0 0;0 0 0;0 0 hL1]; HL2 = [0 0 0;0 0 0;0 0 hL2]; %机械臂杆的惯量
            
            
            kpx_g=str2num(app.kpx_g.Value);
            kix_g=str2num(app.kix_g.Value);
            kdx_g=str2num(app.kdx_g.Value);
            kpz_g=str2num(app.kpz_g.Value);
            kiz_g=str2num(app.kiz_g.Value);
            kdz_g=str2num(app.kdz_g.Value);
            kpq1=str2num(app.kpq1.Value);
            kiq1=str2num(app.kiq1.Value);
            kdq1=str2num(app.kdq1.Value);
            kpq2=str2num(app.kpq2.Value);
            kiq2=str2num(app.kiq2.Value);
            kdq2=str2num(app.kdq2.Value);
            ktd=str2num(app.ktd.Value);
            ktp=str2num(app.ktp.Value);
            
            % 初值
            dxb = 0; dzb = 0; dtheta = 0; dq1 = 0; dq2 = 0;
            q1 = pi/2; q2 = 0; theta = 0;

            B = zeros(5,5); C = zeros(5,5);
            
            %% 计算B初值
            B(1,1) = mL1 + mL2 + mb; B(1,2) = 0;
            B(1,3) = (mL1*L1/2 + mL2*L1)*sin(q1-theta) + (mL2*L2/2)*sin(q1+q2-theta);
            B(1,4) = -(mL1*L1/2 + mL2*L1)*sin(q1-theta) - (mL2*L2/2)*sin(q1+q2-theta);
            B(1,5) = -(mL2*L2/2)*sin(q1+q2-theta);

            B(2,1) = 0; B(2,2) = mL1 + mL2 + mb;
            B(2,3) = -(mL1*L1/2 + mL2*L1)*cos(q1-theta) - (mL2*L2/2)*cos(q1+q2-theta);
            B(2,4) = (mL1*L1/2 + mL2*L1)*cos(q1-theta) + (mL2*L2/2)*cos(q1+q2-theta);
            B(2,5) = (mL2*L2/2)*cos(q1+q2-theta);

            B(3,1) = (mL1*L1/2 + mL2*L1)*sin(q1-theta) + (mL2*L2/2)*sin(q1+q2-theta);
            B(3,2) = -(mL1*L1/2 + mL2*L1)*cos(q1-theta) - (mL2*L2/2)*cos(q1+q2-theta);
            B(3,3) = hL1 + hL2 + Iyy + mL1*(L1/2)^2 + mL2*(L1^2 + (L2/2)^2 + L1*L2*cos(q2)); 
            B(3,4) = -mL2*(L1^2 + (L2/2)^2 + L1*L2*cos(q2)) - mL1*(L1/2)^2;
            B(3,5) = -mL2*L2/2*(L2/2+L1*cos(q2));

            B(4,1) = -(mL1*L1/2 + mL2*L1)*sin(q1-theta) - (mL2*L2/2)*sin(q1+q2-theta);
            B(4,2) = (mL1*L1/2 + mL2*L1)*cos(q1-theta) + (mL2*L2/2)*cos(q1+q2-theta);
            B(4,3) = -mL2*(L1^2 + (L2/2)^2 + L1*L2*cos(q2)) - mL1*(L1/2)^2;
            B(4,4) = mL1*(L1/2)^2 + mL2*(L1^2 + (L2/2)^2 + L1*L2*cos(q2));
            B(4,5) = mL2*L2/2*(L1*cos(q2) + L2/2);

            B(5,1) = -(mL2*L2/2)*sin(q1+q2-theta);
            B(5,2) = (mL2*L2/2)*cos(q1+q2-theta);
            B(5,3) = -mL2*L2/2*(L2/2+L1*cos(q2));
            B(5,4) = mL2*L2/2*(L1*cos(q2) + L2/2);
            B(5,5) = mL2*L2*L2/4;

            B1 = inv(B);

            %% 计算C初值
            C(1,1) = 0; C(1,2) = 0;
            C(1,3) =  dq1*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) - dtheta*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) + (L2*dq2*mL2*cos(q1 + q2 - theta))/2;
            C(1,4) = dtheta*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) - dq1*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) - (L2*dq2*mL2*cos(q1 + q2 - theta))/2;
            C(1,5) = (L2*dtheta*mL2*cos(q1 + q2 - theta))/2 - (L2*dq2*mL2*cos(q1 + q2 - theta))/2 - (L2*dq1*mL2*cos(q1 + q2 - theta))/2;

            C(2,1) = 0; C(2,2) = 0;
            C(2,3) = dq1*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) - dtheta*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) + (L2*dq2*mL2*sin(q1 + q2 - theta))/2;
            C(2,4) = dtheta*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) - dq1*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) - (L2*dq2*mL2*sin(q1 + q2 - theta))/2;
            C(2,5) = (L2*dtheta*mL2*sin(q1 + q2 - theta))/2 - (L2*dq2*mL2*sin(q1 + q2 - theta))/2 - (L2*dq1*mL2*sin(q1 + q2 - theta))/2;
 
            C(3,1) = dq1*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) - dtheta*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) + (L2*dq2*mL2*cos(q1 + q2 - theta))/2;
            C(3,2) = dq1*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) - dtheta*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) + (L2*dq2*mL2*sin(q1 + q2 - theta))/2;
            C(3,3) = - dxb*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) - dzb*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) - (L1*L2*dq2*mL2*sin(q2))/2;
            C(3,4) = dxb*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) + dzb*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) + (L1*L2*dq2*mL2*sin(q2))/2;
            C(3,5) = (L2*dzb*mL2*sin(q1 + q2 - theta))/2 + (L2*dxb*mL2*cos(q1 + q2 - theta))/2 + (L1*L2*dq1*mL2*sin(q2))/2 + (L1*L2*dq2*mL2*sin(q2))/2 - (L1*L2*dtheta*mL2*sin(q2))/2;

            C(4,1) = dtheta*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) - dq1*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) - (L2*dq2*mL2*cos(q1 + q2 - theta))/2;
            C(4,2) = dtheta*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) - dq1*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) - (L2*dq2*mL2*sin(q1 + q2 - theta))/2;
            C(4,3) = dxb*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) + dzb*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) + (L1*L2*dq2*mL2*sin(q2))/2;
            C(4,4) = - dxb*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) - dzb*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) - (L1*L2*dq2*mL2*sin(q2))/2;
            C(4,5) = (L1*L2*dtheta*mL2*sin(q2))/2 - (L2*dxb*mL2*cos(q1 + q2 - theta))/2 - (L1*L2*dq1*mL2*sin(q2))/2 - (L1*L2*dq2*mL2*sin(q2))/2 - (L2*dzb*mL2*sin(q1 + q2 - theta))/2;

            C(5,1) = (L2*dtheta*mL2*cos(q1 + q2 - theta))/2 - (L2*dq2*mL2*cos(q1 + q2 - theta))/2 - (L2*dq1*mL2*cos(q1 + q2 - theta))/2;
            C(5,2) = (L2*dtheta*mL2*sin(q1 + q2 - theta))/2 - (L2*dq2*mL2*sin(q1 + q2 - theta))/2 - (L2*dq1*mL2*sin(q1 + q2 - theta))/2;
            C(5,3) = (L2*dzb*mL2*sin(q1 + q2 - theta))/2 + (L2*dxb*mL2*cos(q1 + q2 - theta))/2 + (L1*L2*dq1*mL2*sin(q2))/2 + (L1*L2*dq2*mL2*sin(q2))/2 - (L1*L2*dtheta*mL2*sin(q2))/2;
            C(5,4) = (L1*L2*dtheta*mL2*sin(q2))/2 - (L2*dxb*mL2*cos(q1 + q2 - theta))/2 - (L1*L2*dq1*mL2*sin(q2))/2 - (L1*L2*dq2*mL2*sin(q2))/2 - (L2*dzb*mL2*sin(q1 + q2 - theta))/2;
            C(5,5) = (L1*L2*dtheta*mL2*sin(q2))/2 - (L2*dxb*mL2*cos(q1 + q2 - theta))/2 - (L1*L2*dq1*mL2*sin(q2))/2 - (L2*dzb*mL2*sin(q1 + q2 - theta))/2;

            C1 = C;
            
            sim('xz2dof_1step',50,options);
            
            cla(app.step_pid);
            plot(app.step_pid,time_grasp,q1_grasp,'-b','linewidth',1.2);
            grid(app.step_pid,"on");
            hold(app.step_pid,"on");
            plot(app.step_pid,time_grasp,q2_grasp,'--r','linewidth',1.2);
            hold(app.step_pid,"off");
            legend(app.step_pid,"q1","q2");
            
            
            % mpc
            T = 10; % 总时间/秒
            tstep = 0.1; % 轨迹规划步长
            step_num = T/tstep; % 轨迹规划步数
            
            Np=str2num(app.Np.Value);
            opt_num=str2num(app.opt_num.Value);

            u0=zeros(Np,5); 
            
            x0 = [4,4,0,pi/2,0,0,0,0,0,0]; x0 = x0';
            xd = [4,4,0,pi/4,pi/4,0,0,0,0,0]; xd = xd';
            
            [u,x_total,v_total] = my_mpc(u0,x0,xd,Np,opt_num);
            
            cla(app.step_mpc);
            plot(app.step_mpc,180/pi*x_total(:,4),'-*b','linewidth',1.2);
            grid(app.step_mpc,"on");
            hold(app.step_mpc,"on");
            plot(app.step_mpc,180/pi*x_total(:,5),'-sr','linewidth',1.2);
            axis(app.step_mpc,[0 20 0 100])
            hold(app.step_mpc,"off");
            legend(app.step_mpc,"q1","q2");


            %% 轨迹规划
            T = 10; % 总时间/秒
            tstep = 0.1; % 轨迹规划步长
            step_num = T/tstep; % 轨迹规划步数
            fly_path=str2num(app.pdes.Value);
            [fly_traj,fly_vel] = mini_snap(fly_path,T,tstep);
            X_n = fly_traj(:,1); X_n = X_n';
            Y_n = fly_traj(:,2); Y_n = Y_n';
            Z_n = fly_traj(:,3); Z_n = Z_n';
            
            plot3(app.fly_traj_s,X_n, Y_n ,Z_n, 'Color', [0 1.0 0], 'LineWidth', 2);  %画出所有轨迹
            hold(app.fly_traj_s,"on") ;
            grid(app.fly_traj_s,"on") ;
            scatter3(app.fly_traj_s,fly_path(1:size(fly_path, 1), 1), fly_path(1:size(fly_path, 1), 2), fly_path(1:size(fly_path, 1), 3)); 
            hold(app.fly_traj_s,"off") ;
                        
            T = 10; % 总时间/秒
            tstep = 0.1; % 轨迹规划步长
            step_num = T/tstep; % 轨迹规划步数
            arm_path=str2num(app.arm_path.Value);
            [arm_traj,arm_vel] = mini_snap(arm_path,T,tstep);
            q1_n = arm_traj(:,1); q1_n = q1_n';
            q2_n = arm_traj(:,2); q2_n = q2_n';
            
            plot(app.arm_traj_s,q1_n, q2_n , 'Color', [0 1.0 0], 'LineWidth', 2);  %画出所有轨迹
            hold(app.arm_traj_s,"on") ;
            grid(app.arm_traj_s,"on") ;
            scatter(app.arm_traj_s,arm_path(1:size(arm_path, 1), 1), arm_path(1:size(arm_path, 1), 2));
            hold(app.arm_traj_s,"off") ;
            
            %循迹抓取
            xz2dof_para;
            sim('xz2dof_traj',50,options);

            y_grasp=y_track(end)*ones(size(time_grasp,1),1);
            psi_grasp=zeros(size(time_grasp,1),1);
            fai_grasp=zeros(size(time_grasp,1),1);

            cla(app.traj_pid);
            plot(app.traj_pid,time_grasp,theta_grasp,'linewidth',1.2);
            grid(app.traj_pid,"on");
            hold(app.traj_pid,"on");
            plot(app.traj_pid,time_grasp,q1_grasp,'linewidth',1.2);
            hold(app.traj_pid,"on");
            plot(app.traj_pid,time_grasp,q2_grasp,'linewidth',1.2);
            hold(app.traj_pid,"off");
            legend(app.traj_pid,'theta','q1','q2');
            
            cla(app.pid_xz);
            plot(app.pid_xz,time_grasp,x_grasp,'linewidth',1.2);
            grid(app.pid_xz,"on");
            hold(app.pid_xz,"on");
            plot(app.pid_xz,time_grasp,z_grasp,'linewidth',1.2);
            hold(app.pid_xz,"off");
            legend(app.pid_xz,'x','z');
            
            
            u_all = [];
            sigma_all = [];
            dsigma_all = [];

            u0 = zeros(Np,5);
            for mpc_i = 1:step_num
                x0 = [4,4,0,arm_traj(mpc_i,:),0,0,0,arm_vel(mpc_i,:)]; x0 = x0';
                xd = [4,4,0,arm_traj(mpc_i+1,:),0,0,0,arm_vel(mpc_i+1,:)]; xd = xd';
                [u,sigma,dsigma] = my_mpc(u0,x0,xd,Np,opt_num);
                u_all = [u_all;u];
                sigma_all = [sigma_all;sigma];
                dsigma_all = [dsigma_all;dsigma];
                u0=[];
                    for u0i = 1:Np
                        u0 = [u0;u(opt_num,:)];
                    end
            end

            plot(app.traj_mpc,180/pi*sigma_all(:,3:5),'linewidth',1.2); 
            grid(app.traj_mpc,"on") ;
            legend(app.traj_mpc,'theta','q1','q2');
            
            plot(app.mpc_xz,sigma_all(:,1:2),'linewidth',1.2); 
            grid(app.mpc_xz,"on") ;
            legend(app.mpc_xz,'x','z');
            
            %% 全程变化
            t_all = [time_track;time_grasp];
            xb_all=[x_track;x_grasp];
            yb_all=[y_track;y_grasp];
            zb_all=[z_track;z_grasp];
            psi_all=[psi_track;psi_grasp];
            theta_all=[theta_track;theta_grasp];
            fai_all=[fai_track;fai_grasp];
            q1_all = [q1_track;q1_grasp];
            q2_all = [q2_track;q2_grasp];
            
            cla(app.position_total);
            plot(app.position_total,t_all,xb_all,'linewidth',1.2);
            grid(app.position_total,"on");
            hold(app.position_total,"on");
            plot(app.position_total,t_all,yb_all,'linewidth',1.2);
            hold(app.position_total,"on");
            plot(app.position_total,t_all,zb_all,'linewidth',1.2);
            hold(app.position_total,"off");
            legend(app.position_total,'x','y','z');
            
            cla(app.attitude_total);
            plot(app.attitude_total,t_all,psi_all,'linewidth',1.2);
            grid(app.attitude_total,"on");
            hold(app.attitude_total,"on");
            plot(app.attitude_total,t_all,theta_all,'linewidth',1.2);
            hold(app.attitude_total,"on");
            plot(app.attitude_total,t_all,fai_all,'linewidth',1.2);
            hold(app.attitude_total,"on");
            plot(app.attitude_total,t_all,q1_all,'linewidth',1.2);
            hold(app.attitude_total,"on");
            plot(app.attitude_total,t_all,q2_all,'linewidth',1.2);
            hold(app.attitude_total,"off");
            legend(app.attitude_total,'psi','theta','phi','q1','q2');
            
            
        end

        % Button pushed function: set_des_0
        function set_des_0ButtonPushed(app, event)
            app.mb.Value = '';
            app.Ixx.Value = '';
            app.Iyy.Value = '';
            app.Izz.Value = '';
            app.L.Value = '';
            
            app.L1.Value = '';
            app.L2.Value = '';
            app.mL1.Value = '';
            app.mL2.Value = '';
            app.hL1.Value = '';
            app.hL2.Value = '';
            
            app.pdes.Value = '';
            app.arm_path.Value = '';
            
            app.kctp.Value='';
            app.kcti.Value='';
            app.kctd.Value='';
            app.katp.Value='';
            app.kati.Value='';  
            
            app.kpph.Value='';
            app.kdph.Value='';
            app.kpt.Value='';
            app.kdt.Value='';
            app.kpp.Value='';
            app.kdp.Value='';
            
            app.kpx.Value='';
            app.kix.Value='';
            app.kdx.Value='';
            app.kpy.Value='';
            app.kiy.Value='';
            app.kdy.Value='';
            app.kpz.Value='';
            app.kiz.Value='';
            app.kdz.Value='';
            
            app.kpx_g.Value='';
            app.kix_g.Value='';
            app.kdx_g.Value='';
            app.kpz_g.Value='';
            app.kiz_g.Value='';
            app.kdz_g.Value='';
            
            app.kpq1.Value='';
            app.kiq1.Value='';
            app.kdq1.Value='';
            
            app.kpq2.Value='';
            app.kiq2.Value='';
            app.kdq2.Value='';
            
            app.ktd.Value='';
            app.ktp.Value='';
            
            app.Np.Value='';
            app.opt_num.Value='';            
        end

        % Button pushed function: start_track
        function start_trackButtonPushed(app, event)
            %% 飞行追踪
            pdes=str2num(app.pdes.Value);
            pointnum=length(pdes)-2;
            final = pdes(length(pdes),:);
            xdes = final(1); ydes = final(2); zdes = final(3);
            vdes = 2;
            psides = 0;
            
            mb=str2num(app.mb.Value);
            mL1=str2num(app.mL1.Value);
            mL2=str2num(app.mL2.Value);
            L=str2num(app.L.Value);
                        
            mq = mb;
            mg = mL1+mL2;
            mobj = 0; 
            ms = mq+mg+mobj;
            
            Ixx=str2num(app.Ixx.Value);
            Iyy=str2num(app.Iyy.Value);
            Izz=str2num(app.Izz.Value);
            
            Kf = 6.11e-8*3600/4/pi^2;
            Km = 1.5e-9*3600/4/pi^2; 
            g = 9.8; 
            km=20;
            
            kctp=str2num(app.kctp.Value);
            kcti=str2num(app.kcti.Value);
            kctd=str2num(app.kctd.Value);
            katp=str2num(app.katp.Value);
            kati=str2num(app.kati.Value);
            kpph=str2num(app.kpph.Value);
            kdph=str2num(app.kdph.Value);
            kpt=str2num(app.kpt.Value);
            kdt=str2num(app.kdt.Value);
            kpp=str2num(app.kpp.Value);
            kdp=str2num(app.kdp.Value);
            kpx=str2num(app.kpx.Value);
            kix=str2num(app.kix.Value);
            kdx=str2num(app.kdx.Value);
            kpy=str2num(app.kpy.Value);
            kiy=str2num(app.kiy.Value);
            kdy=str2num(app.kdy.Value);
            kpz=str2num(app.kpz.Value);
            kiz=str2num(app.kiz.Value);
            kdz=str2num(app.kdz.Value);
            
            wh=sqrt(ms*g/4/Kf);
            
            options = simset('SrcWorkspace','current'); %设当前工作空间为参数变量所在的空间
            
            sim('track',50,options);
            
           
            cla(app.track_position);
            plot(app.track_position,time_track,x_track,'-b','linewidth',1.2);
            hold(app.track_position,"on");
            plot(app.track_position,time_track,y_track,'--r','linewidth',1.2);
            hold(app.track_position,"on");
            plot(app.track_position,time_track,z_track,'-.k','linewidth',1.2);
            hold(app.track_position,"on");
            grid(app.track_position,"on");
            hold(app.track_position,"off");
            legend(app.track_position,"x","y","z");
            
            cla(app.track_attitude);
            plot(app.track_attitude,time_track,psi_track,'-b','linewidth',1.2);
            hold(app.track_attitude,"on");
            plot(app.track_attitude,time_track,theta_track,'--r','linewidth',1.2);
            hold(app.track_attitude,"on");
            plot(app.track_attitude,time_track,fai_track,'-.k','linewidth',1.2);
            hold(app.track_attitude,"on");
            grid(app.track_attitude,"on");
            hold(app.track_attitude,"off");
            legend(app.track_attitude,"psi","theta","phi");
        end

        % Button pushed function: start_1step_grasp
        function start_1step_graspButtonPushed(app, event)
            %% 单步抓取
            mb=str2num(app.mb.Value);
            mL1=str2num(app.mL1.Value);
            mL2=str2num(app.mL2.Value);
            L=str2num(app.L.Value);
                        
            mq = mb;
            mg = mL1+mL2;
            mobj = 0; 
            ms = mq+mg+mobj;
            
            Ixx=str2num(app.Ixx.Value);
            Iyy=str2num(app.Iyy.Value);
            Izz=str2num(app.Izz.Value);
            
            e3 = [0;0;1]; 
            g = 9.8;
            L1=str2num(app.L1.Value);
            L2=str2num(app.L2.Value);
            hL1=str2num(app.hL1.Value);
            hL2=str2num(app.hL2.Value);
            Hb = [Ixx 0 0;0 Iyy 0;0 0 Izz]; % m^2.kg
            HL1 = [0 0 0;0 0 0;0 0 hL1]; HL2 = [0 0 0;0 0 0;0 0 hL2]; %机械臂杆的惯量
            
            
            kpx=str2num(app.kpx_g.Value);
            kix=str2num(app.kix_g.Value);
            kdx=str2num(app.kdx_g.Value);
            kpz=str2num(app.kpz_g.Value);
            kiz=str2num(app.kiz_g.Value);
            kdz=str2num(app.kdz_g.Value);
            kpq1=str2num(app.kpq1.Value);
            kiq1=str2num(app.kiq1.Value);
            kdq1=str2num(app.kdq1.Value);
            kpq2=str2num(app.kpq2.Value);
            kiq2=str2num(app.kiq2.Value);
            kdq2=str2num(app.kdq2.Value);
            ktd=str2num(app.ktd.Value);
            ktp=str2num(app.ktp.Value);
            
            % 初值
            dxb = 0; dzb = 0; dtheta = 0; dq1 = 0; dq2 = 0;
            x_track = 4; y_track = 4;z_track = 4;time_track = 0;
            q1 = pi/2; q2 = 0; theta = 0;

            B = zeros(5,5); C = zeros(5,5);
            
            %% 计算B初值
            B(1,1) = mL1 + mL2 + mb; B(1,2) = 0;
            B(1,3) = (mL1*L1/2 + mL2*L1)*sin(q1-theta) + (mL2*L2/2)*sin(q1+q2-theta);
            B(1,4) = -(mL1*L1/2 + mL2*L1)*sin(q1-theta) - (mL2*L2/2)*sin(q1+q2-theta);
            B(1,5) = -(mL2*L2/2)*sin(q1+q2-theta);

            B(2,1) = 0; B(2,2) = mL1 + mL2 + mb;
            B(2,3) = -(mL1*L1/2 + mL2*L1)*cos(q1-theta) - (mL2*L2/2)*cos(q1+q2-theta);
            B(2,4) = (mL1*L1/2 + mL2*L1)*cos(q1-theta) + (mL2*L2/2)*cos(q1+q2-theta);
            B(2,5) = (mL2*L2/2)*cos(q1+q2-theta);

            B(3,1) = (mL1*L1/2 + mL2*L1)*sin(q1-theta) + (mL2*L2/2)*sin(q1+q2-theta);
            B(3,2) = -(mL1*L1/2 + mL2*L1)*cos(q1-theta) - (mL2*L2/2)*cos(q1+q2-theta);
            B(3,3) = hL1 + hL2 + Iyy + mL1*(L1/2)^2 + mL2*(L1^2 + (L2/2)^2 + L1*L2*cos(q2)); 
            B(3,4) = -mL2*(L1^2 + (L2/2)^2 + L1*L2*cos(q2)) - mL1*(L1/2)^2;
            B(3,5) = -mL2*L2/2*(L2/2+L1*cos(q2));

            B(4,1) = -(mL1*L1/2 + mL2*L1)*sin(q1-theta) - (mL2*L2/2)*sin(q1+q2-theta);
            B(4,2) = (mL1*L1/2 + mL2*L1)*cos(q1-theta) + (mL2*L2/2)*cos(q1+q2-theta);
            B(4,3) = -mL2*(L1^2 + (L2/2)^2 + L1*L2*cos(q2)) - mL1*(L1/2)^2;
            B(4,4) = mL1*(L1/2)^2 + mL2*(L1^2 + (L2/2)^2 + L1*L2*cos(q2));
            B(4,5) = mL2*L2/2*(L1*cos(q2) + L2/2);

            B(5,1) = -(mL2*L2/2)*sin(q1+q2-theta);
            B(5,2) = (mL2*L2/2)*cos(q1+q2-theta);
            B(5,3) = -mL2*L2/2*(L2/2+L1*cos(q2));
            B(5,4) = mL2*L2/2*(L1*cos(q2) + L2/2);
            B(5,5) = mL2*L2*L2/4;

            B1 = inv(B);

            %% 计算C初值
            C(1,1) = 0; C(1,2) = 0;
            C(1,3) =  dq1*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) - dtheta*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) + (L2*dq2*mL2*cos(q1 + q2 - theta))/2;
            C(1,4) = dtheta*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) - dq1*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) - (L2*dq2*mL2*cos(q1 + q2 - theta))/2;
            C(1,5) = (L2*dtheta*mL2*cos(q1 + q2 - theta))/2 - (L2*dq2*mL2*cos(q1 + q2 - theta))/2 - (L2*dq1*mL2*cos(q1 + q2 - theta))/2;

            C(2,1) = 0; C(2,2) = 0;
            C(2,3) = dq1*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) - dtheta*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) + (L2*dq2*mL2*sin(q1 + q2 - theta))/2;
            C(2,4) = dtheta*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) - dq1*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) - (L2*dq2*mL2*sin(q1 + q2 - theta))/2;
            C(2,5) = (L2*dtheta*mL2*sin(q1 + q2 - theta))/2 - (L2*dq2*mL2*sin(q1 + q2 - theta))/2 - (L2*dq1*mL2*sin(q1 + q2 - theta))/2;
 
            C(3,1) = dq1*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) - dtheta*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) + (L2*dq2*mL2*cos(q1 + q2 - theta))/2;
            C(3,2) = dq1*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) - dtheta*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) + (L2*dq2*mL2*sin(q1 + q2 - theta))/2;
            C(3,3) = - dxb*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) - dzb*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) - (L1*L2*dq2*mL2*sin(q2))/2;
            C(3,4) = dxb*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) + dzb*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) + (L1*L2*dq2*mL2*sin(q2))/2;
            C(3,5) = (L2*dzb*mL2*sin(q1 + q2 - theta))/2 + (L2*dxb*mL2*cos(q1 + q2 - theta))/2 + (L1*L2*dq1*mL2*sin(q2))/2 + (L1*L2*dq2*mL2*sin(q2))/2 - (L1*L2*dtheta*mL2*sin(q2))/2;

            C(4,1) = dtheta*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) - dq1*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) - (L2*dq2*mL2*cos(q1 + q2 - theta))/2;
            C(4,2) = dtheta*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) - dq1*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) - (L2*dq2*mL2*sin(q1 + q2 - theta))/2;
            C(4,3) = dxb*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) + dzb*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) + (L1*L2*dq2*mL2*sin(q2))/2;
            C(4,4) = - dxb*(cos(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*cos(q1 + q2 - theta))/2) - dzb*(sin(q1 - theta)*((L1*mL1)/2 + L1*mL2) + (L2*mL2*sin(q1 + q2 - theta))/2) - (L1*L2*dq2*mL2*sin(q2))/2;
            C(4,5) = (L1*L2*dtheta*mL2*sin(q2))/2 - (L2*dxb*mL2*cos(q1 + q2 - theta))/2 - (L1*L2*dq1*mL2*sin(q2))/2 - (L1*L2*dq2*mL2*sin(q2))/2 - (L2*dzb*mL2*sin(q1 + q2 - theta))/2;

            C(5,1) = (L2*dtheta*mL2*cos(q1 + q2 - theta))/2 - (L2*dq2*mL2*cos(q1 + q2 - theta))/2 - (L2*dq1*mL2*cos(q1 + q2 - theta))/2;
            C(5,2) = (L2*dtheta*mL2*sin(q1 + q2 - theta))/2 - (L2*dq2*mL2*sin(q1 + q2 - theta))/2 - (L2*dq1*mL2*sin(q1 + q2 - theta))/2;
            C(5,3) = (L2*dzb*mL2*sin(q1 + q2 - theta))/2 + (L2*dxb*mL2*cos(q1 + q2 - theta))/2 + (L1*L2*dq1*mL2*sin(q2))/2 + (L1*L2*dq2*mL2*sin(q2))/2 - (L1*L2*dtheta*mL2*sin(q2))/2;
            C(5,4) = (L1*L2*dtheta*mL2*sin(q2))/2 - (L2*dxb*mL2*cos(q1 + q2 - theta))/2 - (L1*L2*dq1*mL2*sin(q2))/2 - (L1*L2*dq2*mL2*sin(q2))/2 - (L2*dzb*mL2*sin(q1 + q2 - theta))/2;
            C(5,5) = (L1*L2*dtheta*mL2*sin(q2))/2 - (L2*dxb*mL2*cos(q1 + q2 - theta))/2 - (L1*L2*dq1*mL2*sin(q2))/2 - (L2*dzb*mL2*sin(q1 + q2 - theta))/2;

            C1 = C;
            
            
            options = simset('SrcWorkspace','current'); %设当前工作空间为参数变量所在的空间
            sim('xz2dof_1step',50,options);
            
            cla(app.step_pid);
            plot(app.step_pid,time_grasp,q1_grasp,'-b','linewidth',1.2);
            grid(app.step_pid,"on");
            hold(app.step_pid,"on");
            plot(app.step_pid,time_grasp,q2_grasp,'--r','linewidth',1.2);
            hold(app.step_pid,"off");
            legend(app.step_pid,"q1","q2");
            
            
            % mpc
            T = 10; % 总时间/秒
            tstep = 0.1; % 轨迹规划步长
            step_num = T/tstep; % 轨迹规划步数
            
            Np=str2num(app.Np.Value);
            opt_num=str2num(app.opt_num.Value);

            u0=zeros(Np,5); 
            
            x0 = [4,4,0,pi/2,0,0,0,0,0,0]; x0 = x0';
            xd = [4,4,0,pi/4,pi/4,0,0,0,0,0]; xd = xd';
            
            [u,x_total,v_total] = my_mpc(u0,x0,xd,Np,opt_num);
            
            cla(app.step_mpc);
            plot(app.step_mpc,180/pi*x_total(:,4),'-*b','linewidth',1.2);
            grid(app.step_mpc,"on");
            hold(app.step_mpc,"on");
            plot(app.step_mpc,180/pi*x_total(:,5),'-sr','linewidth',1.2);
            axis(app.step_mpc,[0 20 0 100])
            hold(app.step_mpc,"off");
            legend(app.step_mpc,"q1","q2");            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.LeftPanel);
            app.TabGroup3 = uitabgroup(app.GridLayout7);
            app.Tab = uitab(app.TabGroup3);
            app.Tab.Title = '飞机参数';
            app.kgEditFieldLabel_2 = uilabel(app.Tab);
            app.kgEditFieldLabel_2.Text = '质量(kg)';
            app.mb = uieditfield(app.Tab, 'text');
            app.IxxEditFieldLabel_2 = uilabel(app.Tab);
            app.IxxEditFieldLabel_2.Text = 'Ixx';
            app.Ixx = uieditfield(app.Tab, 'text');
            app.IyyEditFieldLabel_2 = uilabel(app.Tab);
            app.IyyEditFieldLabel_2.Text = 'Iyy';
            app.Iyy = uieditfield(app.Tab, 'text');
            app.IzzEditFieldLabel_2 = uilabel(app.Tab);
            app.IzzEditFieldLabel_2.Text = 'Izz';
            app.Izz = uieditfield(app.Tab, 'text');
            app.mEditFieldLabel_2 = uilabel(app.Tab);
            app.mEditFieldLabel_2.Text = '臂长(m)';
            app.L = uieditfield(app.Tab, 'text');
            app.Tab_2 = uitab(app.TabGroup3);
            app.Tab_2.Title = '机械臂参数';
            app.mEditField_2Label = uilabel(app.Tab_2);
            app.mEditField_2Label.Text = '臂1长度(m)';
            app.L1 = uieditfield(app.Tab_2, 'text');
            app.mEditField_3Label = uilabel(app.Tab_2);
            app.mEditField_3Label.Text = '臂2长度(m)';
            app.L2 = uieditfield(app.Tab_2, 'text');
            app.kgEditField_2Label = uilabel(app.Tab_2);
            app.kgEditField_2Label.Text = '臂1质量(kg)';
            app.mL1 = uieditfield(app.Tab_2, 'text');
            app.kgEditField_3Label = uilabel(app.Tab_2);
            app.kgEditField_3Label.Text = '臂2质量(kg)';
            app.mL2 = uieditfield(app.Tab_2, 'text');
            app.EditField_3Label = uilabel(app.Tab_2);
            app.EditField_3Label.Text = '臂1惯量';
            app.hL1 = uieditfield(app.Tab_2, 'text');
            app.EditField_4Label = uilabel(app.Tab_2);
            app.EditField_4Label.Text = '臂2惯量';
            app.hL2 = uieditfield(app.Tab_2, 'text');
            app.Panel = uipanel(app.GridLayout7);
            app.Panel.Title = '目标设置';
            app.set_des_0 = uibutton(app.Panel, 'push');
            app.set_des_0.ButtonPushedFcn = createCallbackFcn(app, @set_des_0ButtonPushed, true);
            app.set_des_0.Text = '清零';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '飞行关键点';
            app.pdes = uieditfield(app.Panel, 'text');
            app.EditField_2Label = uilabel(app.Panel);
            app.EditField_2Label.Text = '抓取关键点';
            app.arm_path = uieditfield(app.Panel, 'text');
            app.default_all = uibutton(app.Panel, 'push');
            app.default_all.ButtonPushedFcn = createCallbackFcn(app, @default_allButtonPushed, true);
            app.default_all.Text = '全部使用默认参数';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.TabGroup2 = uitabgroup(app.GridLayout4);
            app.track = uitab(app.TabGroup2);
            app.track.Title = '飞行跟踪';
            app.GridLayout6 = uigridlayout(app.track);
            app.track_position = uiaxes(app.GridLayout6);
            title(app.track_position, '飞行跟踪-位置')
            xlabel(app.track_position, 't/s')
            ylabel(app.track_position, '位置/m')
            app.track_attitude = uiaxes(app.GridLayout6);
            title(app.track_attitude, '飞行跟踪-姿态')
            xlabel(app.track_attitude, 't/s')
            ylabel(app.track_attitude, '姿态角/°')
            app.grasp1step = uitab(app.TabGroup2);
            app.grasp1step.Title = '单步抓取';
            app.GridLayout8 = uigridlayout(app.grasp1step);
            app.step_pid = uiaxes(app.GridLayout8);
            title(app.step_pid, '机械臂阶跃响应-pid')
            xlabel(app.step_pid, 't/s')
            ylabel(app.step_pid, '关节变量/°')
            app.step_mpc = uiaxes(app.GridLayout8);
            title(app.step_mpc, '机械臂阶跃响应-MPC')
            xlabel(app.step_mpc, 't/s')
            ylabel(app.step_mpc, '关节变量/°')
            app.trajectory = uitab(app.TabGroup2);
            app.trajectory.Title = '轨迹规划';
            app.GridLayout9 = uigridlayout(app.trajectory);
            app.fly_traj_s = uiaxes(app.GridLayout9);
            title(app.fly_traj_s, '飞行轨迹规划')
            xlabel(app.fly_traj_s, 'X')
            ylabel(app.fly_traj_s, 'Y')
            app.arm_traj_s = uiaxes(app.GridLayout9);
            title(app.arm_traj_s, '抓取轨迹规划')
            xlabel(app.arm_traj_s, 'q1/rad')
            ylabel(app.arm_traj_s, 'q2/rad')
            app.grasp_traj = uitab(app.TabGroup2);
            app.grasp_traj.Title = '循迹抓取';
            app.traj_grasp = uigridlayout(app.grasp_traj);
            app.traj_pid = uiaxes(app.traj_grasp);
            title(app.traj_pid, 'pid控制器-角度')
            xlabel(app.traj_pid, 't/s')
            ylabel(app.traj_pid, '角度/°')
            app.traj_mpc = uiaxes(app.traj_grasp);
            title(app.traj_mpc, 'MPC控制器-角度')
            xlabel(app.traj_mpc, 't/次')
            ylabel(app.traj_mpc, '角度/°')
            app.pid_xz = uiaxes(app.traj_grasp);
            title(app.pid_xz, 'pid控制器-位移')
            xlabel(app.pid_xz, 't/s')
            ylabel(app.pid_xz, '位移/m')
            app.mpc_xz = uiaxes(app.traj_grasp);
            title(app.mpc_xz, 'MPC控制器-位移')
            xlabel(app.mpc_xz, 't/次')
            ylabel(app.mpc_xz, '位移/m')
            app.Tab_3 = uitab(app.TabGroup2);
            app.Tab_3.Title = '全程变化';
            app.total = uigridlayout(app.Tab_3);
            app.position_total = uiaxes(app.total);
            title(app.position_total, '位置')
            xlabel(app.position_total, 't/s')
            ylabel(app.position_total, '位置/m')
            app.attitude_total = uiaxes(app.total);
            title(app.attitude_total, '角度')
            xlabel(app.attitude_total, 't/s')
            ylabel(app.attitude_total, '角度/°')
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.RightPanel);
            app.control_para = uitabgroup(app.GridLayout5);
            app.track_control = uitab(app.control_para);
            app.track_control.Title = '飞行跟踪';
            app.kctiLabel = uilabel(app.track_control);
            app.kctiLabel.Text = 'kcti';
            app.kcti = uieditfield(app.track_control, 'text');
            app.kctdLabel = uilabel(app.track_control);
            app.kctdLabel.Text = 'kctd';
            app.kctd = uieditfield(app.track_control, 'text');
            app.katpLabel = uilabel(app.track_control);
            app.katpLabel.Text = 'katp';
            app.katp = uieditfield(app.track_control, 'text');
            app.katiLabel = uilabel(app.track_control);
            app.katiLabel.Text = 'kati';
            app.kati = uieditfield(app.track_control, 'text');
            app.kctpLabel = uilabel(app.track_control);
            app.kctpLabel.Text = 'kctp';
            app.kctp = uieditfield(app.track_control, 'text');
            app.attitude_control = uitab(app.control_para);
            app.attitude_control.Title = '姿态控制';
            app.kppEditFieldLabel = uilabel(app.attitude_control);
            app.kppEditFieldLabel.Text = 'kpp';
            app.kpp = uieditfield(app.attitude_control, 'text');
            app.kdpEditFieldLabel = uilabel(app.attitude_control);
            app.kdpEditFieldLabel.Text = 'kdp';
            app.kdp = uieditfield(app.attitude_control, 'text');
            app.kptLabel = uilabel(app.attitude_control);
            app.kptLabel.Text = 'kpt';
            app.kpt = uieditfield(app.attitude_control, 'text');
            app.kdtLabel = uilabel(app.attitude_control);
            app.kdtLabel.Text = 'kdt';
            app.kdt = uieditfield(app.attitude_control, 'text');
            app.kpphLabel = uilabel(app.attitude_control);
            app.kpphLabel.Text = 'kpph';
            app.kpph = uieditfield(app.attitude_control, 'text');
            app.kdphLabel = uilabel(app.attitude_control);
            app.kdphLabel.Text = 'kdph';
            app.kdph = uieditfield(app.attitude_control, 'text');
            app.hover = uitab(app.control_para);
            app.hover.Title = '悬停';
            app.kdxEditFieldLabel = uilabel(app.hover);
            app.kdxEditFieldLabel.Text = 'kdx';
            app.kdx = uieditfield(app.hover, 'text');
            app.kpxEditFieldLabel = uilabel(app.hover);
            app.kpxEditFieldLabel.Text = 'kpx';
            app.kpx = uieditfield(app.hover, 'text');
            app.kixEditFieldLabel = uilabel(app.hover);
            app.kixEditFieldLabel.Text = 'kix';
            app.kix = uieditfield(app.hover, 'text');
            app.kdyEditFieldLabel = uilabel(app.hover);
            app.kdyEditFieldLabel.Text = 'kdy';
            app.kdy = uieditfield(app.hover, 'text');
            app.kpyEditFieldLabel = uilabel(app.hover);
            app.kpyEditFieldLabel.Text = 'kpy';
            app.kpy = uieditfield(app.hover, 'text');
            app.kiyEditFieldLabel = uilabel(app.hover);
            app.kiyEditFieldLabel.Text = 'kiy';
            app.kiy = uieditfield(app.hover, 'text');
            app.kdzEditFieldLabel = uilabel(app.hover);
            app.kdzEditFieldLabel.Text = 'kdz';
            app.kdz = uieditfield(app.hover, 'text');
            app.kpzEditFieldLabel = uilabel(app.hover);
            app.kpzEditFieldLabel.Text = 'kpz';
            app.kpz = uieditfield(app.hover, 'text');
            app.kizEditFieldLabel = uilabel(app.hover);
            app.kizEditFieldLabel.Text = 'kiz';
            app.kiz = uieditfield(app.hover, 'text');
            app.grasp_pid = uitab(app.control_para);
            app.grasp_pid.Title = '抓取pid';
            app.kpxEditFieldLabel_2 = uilabel(app.grasp_pid);
            app.kpxEditFieldLabel_2.Text = 'kpx';
            app.kpx_g = uieditfield(app.grasp_pid, 'text');
            app.kixEditFieldLabel_2 = uilabel(app.grasp_pid);
            app.kixEditFieldLabel_2.Text = 'kix';
            app.kix_g = uieditfield(app.grasp_pid, 'text');
            app.kdxEditField_3Label = uilabel(app.grasp_pid);
            app.kdxEditField_3Label.Text = 'kdx';
            app.kdx_g = uieditfield(app.grasp_pid, 'text');
            app.kpzEditFieldLabel_2 = uilabel(app.grasp_pid);
            app.kpzEditFieldLabel_2.Text = 'kpz';
            app.kpz_g = uieditfield(app.grasp_pid, 'text');
            app.kizEditFieldLabel_2 = uilabel(app.grasp_pid);
            app.kizEditFieldLabel_2.Text = 'kiz';
            app.kiz_g = uieditfield(app.grasp_pid, 'text');
            app.kdzEditFieldLabel_2 = uilabel(app.grasp_pid);
            app.kdzEditFieldLabel_2.Text = 'kdz';
            app.kdz_g = uieditfield(app.grasp_pid, 'text');
            app.kpq1EditFieldLabel = uilabel(app.grasp_pid);
            app.kpq1EditFieldLabel.Text = 'kpq1';
            app.kpq1 = uieditfield(app.grasp_pid, 'text');
            app.kiq1EditFieldLabel = uilabel(app.grasp_pid);
            app.kiq1EditFieldLabel.Text = 'kiq1';
            app.kiq1 = uieditfield(app.grasp_pid, 'text');
            app.kdq1EditFieldLabel = uilabel(app.grasp_pid);
            app.kdq1EditFieldLabel.Text = 'kdq1';
            app.kdq1 = uieditfield(app.grasp_pid, 'text');
            app.kpq2EditFieldLabel = uilabel(app.grasp_pid);
            app.kpq2EditFieldLabel.Text = 'kpq2';
            app.kpq2 = uieditfield(app.grasp_pid, 'text');
            app.kiq2EditFieldLabel = uilabel(app.grasp_pid);
            app.kiq2EditFieldLabel.Text = 'kiq2';
            app.kiq2 = uieditfield(app.grasp_pid, 'text');
            app.kdq2EditFieldLabel = uilabel(app.grasp_pid);
            app.kdq2EditFieldLabel.Text = 'kdq2';
            app.kdq2 = uieditfield(app.grasp_pid, 'text');
            app.ktpEditFieldLabel = uilabel(app.grasp_pid);
            app.ktpEditFieldLabel.Text = 'ktp';
            app.ktp = uieditfield(app.grasp_pid, 'text');
            app.ktdEditFieldLabel = uilabel(app.grasp_pid);
            app.ktdEditFieldLabel.Text = 'ktd';
            app.ktd = uieditfield(app.grasp_pid, 'text');
            app.grasp_mpc = uitab(app.control_para);
            app.grasp_mpc.Title = '抓取MPC';
            app.NpEditFieldLabel = uilabel(app.grasp_mpc);
            app.NpEditFieldLabel.Text = '预测步长Np';
            app.Np = uieditfield(app.grasp_mpc, 'text');
            app.EditField_5Label = uilabel(app.grasp_mpc);
            app.EditField_5Label.Text = '优化次数';
            app.opt_num = uieditfield(app.grasp_mpc, 'text');
            app.Panel_2 = uipanel(app.GridLayout5);
            app.Panel_2.Title = '仿真控制';
            app.start_simulation = uibutton(app.Panel_2, 'push');
            app.start_simulation.ButtonPushedFcn = createCallbackFcn(app, @start_simulationButtonPushed, true);
            app.start_simulation.Text = '开始全部仿真';
            app.start_track = uibutton(app.Panel_2, 'push');
            app.start_track.ButtonPushedFcn = createCallbackFcn(app, @start_trackButtonPushed, true);
            app.start_track.Text = '轨迹跟踪调试';
            app.start_1step_grasp = uibutton(app.Panel_2, 'push');
            app.start_1step_grasp.ButtonPushedFcn = createCallbackFcn(app, @start_1step_graspButtonPushed, true);
            app.start_1step_grasp.Text = '单步抓取调试';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aerial_arm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end